classdef app_draft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        OptionsTab                  matlab.ui.container.Tab
        Panel_2                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        OffsetButton                matlab.ui.control.Button
        CalibrateButton             matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        STARTSTOPACQUISITIONButton  matlab.ui.control.Button
        ForceTab                    matlab.ui.container.Tab
        RotationTab                 matlab.ui.container.Tab
        EMGTab                      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Status = 0 % Description
        tcpScoket % Description
        Fsamp = 2048 % Description
        nsamp = 128 % Description
        n_grids = 1 % Description
        EMG % Description
        TCPPort
        ConfString
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTSTOPACQUISITIONButton
        function STARTSTOPACQUISITIONButtonPushed(app, event)
            if app.Status == 1 % 0 = Not connected; 1 = Connected
                % Stop connection to OTB
                app.ConfString(1) = bin2dec('10000000');    % First byte that stop the data transer
                app.ConfString(40) = CRC8(app.ConfString, 39);  % Estimates the new CRC
                fwrite(app.tcpScoket, app.ConfString, 'uint8');
                fclose(app.tcpScoket);
                app.Lamp.Color = [1 0 0];
                app.Status = 0;
            elseif app.Status == 0
                % Start connection to OTB
                %_______________OT QUATTROCENTO__________________              
                % TCP socket definition
               app.tcpScoket = tcpip("localhost", 3100);
               app.tcpSocket.InputBufferSize = 408*app.Fsamp*10;
               app.tcpScoket.InputBufferSize = 2*app.NumChanVal(app.NCHsel)*app.FsampVal(app.FSsel);

                try
                    fopen(app.tcpScoket);
                    set(app.tcpScoket, 'ByteOrder', 'littleEndian');
                catch
                    disp('Cannot connect');
                    app.Lamp.Color = [1 0 0];
                    app.Status = 0;
                end

                if strcmp(app.tcpScoket.Status,'open')
                    fwrite(app.tcpScoket, app.ConfString, 'uint8');
                    app.Lamp.Color = [0 1 0];
                    app.Status = 1;
                else
                    app.Lamp.Color = [1 0 0];
                    app.Status =0;
                end
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            triallength = app.FsampVal(app.FSsel)*30; %Trial = 30s
             time = linspace(0,5,triallength);
             buffer = zeros(64*app.n_grids,triallength);
             nwin = floor(triallength/app.nsamp);
             nwinbuff = 1;
             nwinbuffmax = floor(triallength/app.nsamp);
             app.EMG = zeros(64*app.n_grids,nwin*app.nsamp);
             i = 1;
             fwrite(app.tcpScoket,'startTX');
             app.CalibrateButton.BackgroundColor = [0 1 0]; % Turn green [If connection was established]
             pause(0.05);
             channels = 1:1:64*app.n_grids;
             offset = ones(64*app.n_grids,app.nsamp);
             offset = offset .* channels';
             while i <= nwin
                 signal = fread(app.tcpSocket,[408,app.nsamp],'int16');
                 % signal = fread(app.tcpScoket,[216, app.nsamp],'int16');
                 buffer(:,(nwinbuff-1)*app.nsamp+1:nwinbuff*app.nsamp) = signal(129+4:128+4+64*app.n_grids,:) * 0.0005 + offset;
                 
                 app.EMG(:,(i-1)*app.nsamp+1:i*app.nsamp) = signal(129+4 : 128+4+64*app.n_grids, :);

                 plot(app.UIAxes, time, buffer, 'LineWidth', 1, 'Color', 'k');
                 app.UIAxes.XLim = [0 6];
                 app.UIAxes.YLim = [0 130];
                 line(app.UIAxes, [time(nwinbuff*app.nsamp) time(nwinbuff*app.nsamp)], [0 64], 'LineWidth', 1, 'Color', 'k');
                 drawnow limitrate
                 i = i + 1;
                 if nwinbuff < nwinbuffmax
                     nwinbuff = nwinbuff+1;
                 else
                     nwinbuff = 1;
                 end
                 clc
             end

             fwrite(app.tcpScoket,'stopTX');
             fclose(app.tcpScoket);
             plot(app.UIAxes, 0, 0, 'LineWidth', 1, 'Color', 'k');
             drawnow
             %assignin("base","EMG",app.EMG)
             app.CalibrateButton.BackgroundColor = [0.96 0.96 0.96]; % Turn red [If connection was severed]
             pause(0.5);
             app.CalibrateButton.BackgroundColor = [0 1 0]; % Turn green [If connection was established]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Panel = uipanel(app.OptionsTab);
            app.STARTSTOPACQUISITIONButton = uibutton(app.Panel, 'push');
            app.STARTSTOPACQUISITIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSTOPACQUISITIONButtonPushed, true);
            app.STARTSTOPACQUISITIONButton.Text = 'START/STOP ACQUISITION';
            app.Lamp = uilamp(app.Panel);
            app.CalibrateButton = uibutton(app.Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.OffsetButton = uibutton(app.Panel, 'push');
            app.OffsetButton.Text = 'Offset';
            app.Lamp_2 = uilamp(app.Panel);
            app.Lamp_3 = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.OptionsTab);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ForceTab = uitab(app.TabGroup);
            app.ForceTab.Title = 'Force';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_draft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end