classdef app_draft_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        OptionsTab                  matlab.ui.container.Tab
        Panel_2                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        OffsetButton                matlab.ui.control.Button
        CalibrateButton             matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        STARTSTOPACQUISITIONButton  matlab.ui.control.Button
        ForceTab                    matlab.ui.container.Tab
        RotationTab                 matlab.ui.container.Tab
        EMGTab                      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Status = 0 % 0 = Not connected; 1 = Connected
        tcpScoket 
        
        % Sampling frequency values
        Fsamp = [0 8 16 24] % Set the sampling frequency
        FsampVal = [512 2048 5120 10240]
        NumChan = [0 2 4 6]  % Codes to set the number of channels
        NumChanVal = [120 216 312 408]
        FSsel = 2 % FSsel  = 1 -> 512 Hz; FSsel  = 2 -> 2048 Hz; FSsel  = 3 -> 5120 Hz; FSsel  = 4 -> 10240 Hz
        nsamp = 128
        n_grids = 1 
        NCHsel = 4 % NCHsel = 1 -> IN1, IN2, MULTIPLE IN1, AUX IN; NCHsel = 2 -> IN1..IN4, MULTIPLE IN1, MULTIPLE IN2, AUX IN; NCHsel = 3 -> IN1..IN6, MULTIPLE IN1..MULTIPLE IN3, AUX IN;  NCHsel = 4 -> IN1..IN8, MULTIPLE IN1..MULTIPLE IN4, AUX IN
        
        EMG % Description
        TCPPort
        ConfString
        NumSampBlockRead
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTSTOPACQUISITIONButton
        function STARTSTOPACQUISITIONButtonPushed(app, event)
            if app.Status == 1
                % Stop connection to OTB
                disconnect_OT_Quattrocento;
                app.Lamp.Color = [1 0 0];
                app.Status = 0;
            elseif app.Status == 0
                % Start connection to OTB
                [app.ConfString, app.tcpScoket, app.NumChanVal, app.NCHsel, app.NumSampBlockRead]=connect_OT_Quattrocento;
                try
                    fopen(app.tcpScoket);
                    set(app.tcpScoket, 'ByteOrder', 'littleEndian');
                catch
                    disp('Cannot connect');
                    app.Lamp.Color = [1 0 0];
                    app.Status = 0;
                end

                if strcmp(app.tcpScoket.Status,'open')
                    fwrite(app.tcpScoket, app.ConfString, 'uint8');
                    app.Lamp.Color = [0 1 0];
                    while app.tcpScoket.BytesAvailable <= app.NumChanVal(app.NCHsel)*app.NumSampBlockRead
                        pause(0.001);
                    end
                    app.Status = 1;
                else
                    app.Lamp.Color = [1 0 0];
                    app.Status =0;
                end
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
             NumRefreshPerEpoch = 0.25/0.125;
             Duration = 30;
             NumCycles = Duration/RefreshRate;
             triallength = app.FsampVal(app.FSsel)*Duration; %Trial = 30s
             time = linspace(0,5,triallength);
             buffer = zeros(64*app.n_grids,triallength);
             nwin = floor(triallength/app.nsamp);
             nwinbuffmax = floor(triallength/app.nsamp);
             app.EMG = zeros(64*app.n_grids,nwin*app.nsamp);
             channels = 1:1:64*app.n_grids;
             offset = ones(64*app.n_grids,app.nsamp);
             offset = offset .* channels';
             
             fwrite(app.tcpScoket,'startTX');
             app.CalibrateButton.BackgroundColor = [0 1 0]; % Turn green [If connection was established]
             pause(0.01);
            tstart = tic;
            for i = 1: NumCycles
                while toc(tstart) <= 0.125
                    pause(0.001);
                end
                tstart = tic;
                try
                    signal = fread(app.tcpScoket, [app.NumChanVal(app.NCHsel), app.NumSampBlockRead], 'int16')';
                    disp(app.NumChanVal(app.NCHsel));
                    disp(length(signal));
                
                %buffer(:,(nwinbuff-1)*app.nsamp+1:nwinbuff*app.nsamp) = signal(129+4:128+4+64*app.n_grids,:) * 0.0005 + offset;
                %app.EMG(:,(i-1)*app.nsamp+1:i*app.nsamp) = signal(129+4 : 128+4+64*app.n_grids, :);
                
                    plot(app.UIAxes, time, signal, 'LineWidth', 1, 'Color', 'k');
                    app.UIAxes.XLim = [0 6];
                    app.UIAxes.YLim = [0 130];
                    clc
                catch
                    break;
                end
            end

             disconnect_OT_Quattrocento
             plot(app.UIAxes, 0, 0, 'LineWidth', 1, 'Color', 'k');
             drawnow
             %assignin("base","EMG",app.EMG)
             app.CalibrateButton.BackgroundColor = [0.96 0.96 0.96]; % Turn red [If connection was severed]
             pause(0.5);
             app.CalibrateButton.BackgroundColor = [0 1 0]; % Turn green [If connection was established]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Panel = uipanel(app.OptionsTab);
            app.STARTSTOPACQUISITIONButton = uibutton(app.Panel, 'push');
            app.STARTSTOPACQUISITIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSTOPACQUISITIONButtonPushed, true);
            app.STARTSTOPACQUISITIONButton.Text = 'START/STOP ACQUISITION';
            app.Lamp = uilamp(app.Panel);
            app.CalibrateButton = uibutton(app.Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.OffsetButton = uibutton(app.Panel, 'push');
            app.OffsetButton.Text = 'Offset';
            app.Lamp_2 = uilamp(app.Panel);
            app.Lamp_3 = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.OptionsTab);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ForceTab = uitab(app.TabGroup);
            app.ForceTab.Title = 'Force';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_draft_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end